classdef Z2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIF2               matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        Button             matlab.ui.control.Button
        TextArea           matlab.ui.control.TextArea
        UIAxes_3           matlab.ui.control.UIAxes
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
        Tab_2              matlab.ui.container.Tab
        TextArea_3         matlab.ui.control.TextArea
        Button_2           matlab.ui.control.Button
        Label_3            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        yt4yt6ytftftLabel  matlab.ui.control.Label
        UIAxes4            matlab.ui.control.UIAxes
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        Tab_3              matlab.ui.container.Tab
        t0p5xexp2tLabel    matlab.ui.control.Label
        TextArea_2         matlab.ui.control.TextArea
        Label_4            matlab.ui.control.Label
        UIAxes5            matlab.ui.control.UIAxes
        Tab_4              matlab.ui.container.Tab
        Button_3           matlab.ui.control.Button
        txLabel            matlab.ui.control.Label
        fteLabel           matlab.ui.control.Label
        yt5yt6ytftLabel    matlab.ui.control.Label
        Label_5            matlab.ui.control.Label
        EditField          matlab.ui.control.NumericEditField
        UIAxes6            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            dt=0.01;
            t=-1:dt:2.5;
            f1=heaviside(t)-0.5*heaviside(t-2);
            f2=2*exp(-3*t).*heaviside(t);
            f=conv(f1,f2)*dt;
            n=length(f);
            tt=(0:n-1)*dt-2;

            plot(app.UIAxes,t,f1,'r');
            grid(app.UIAxes,"on");

            app.UIAxes.Title.String=('f1(t)');
            app.UIAxes.XLabel.String=('时间(t)') ;
            app.UIAxes.YLabel.String=('幅值（f）');
            app.UIAxes.XAxis.Limits=[-1,2.5];
            app.UIAxes.YAxis.Limits=[-0.2,1.2];


            plot(app.UIAxes_2,t,f2,'r');
            grid(app.UIAxes_2,"on");
            app.UIAxes_2.Title.String=('f2(t)');
            app.UIAxes_2.XLabel.String=('时间(t)') ;
            app.UIAxes_2.YLabel.String=('幅值（f）');
            app.UIAxes_2.XAxis.Limits=[-1,2.5];
            app.UIAxes_2.YAxis.Limits=[-0.2,1.2];


            plot(app.UIAxes_3,tt,f,'r');
            grid(app.UIAxes_3,"on");
            app.UIAxes_2.Title.String=('f1(t)*f2(t)  信号卷积');
            app.UIAxes_2.XLabel.String=('时间(t)') ;
            app.UIAxes_2.YLabel.String=('幅值（f）');
            %app.UIAxes_2.XAxis.Limits=[-1,2.5];
            app.UIAxes_3.YAxis.Limits=[-0.2,0.8];
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            a=[7 4 6];
            b=[1 1];
            t=0:0.01:10;
            
            h=impulse(b,a);
            
            plot(app.UIAxes2,h,'r');
            app.UIAxes2.Title.String=('冲激响应');
            app.UIAxes2.XLabel.String=('时间(t)') ;
            app.UIAxes2.YLabel.String=('幅值（f）');
            %app.UIAxes2.XAxis.Limits=[0,5];
            %app.UIAxes2.YAxis.Limits=[-0.2,0.2];

            
            x=step(b,a);
            plot(app.UIAxes3,x,'r');
            app.UIAxes3.Title.String=('阶跃响应');
            app.UIAxes3.XLabel.String=('时间(t)') ;
            app.UIAxes3.YLabel.String=('幅值（f）');
            %app.UIAxes3.XAxis.Limits=[0,5];
            %app.UIAxes3.YAxis.Limits=[-0.2,0.2];


            sys=tf(b,a);
            A=0.5;
            T=2;
            w=2*pi/T;
            f=0.5+A*square(w*t,50);
            y=lsim(sys,f,t );
            plot(app.UIAxes4,t,y,'r');
             
            
            app.UIAxes4.Title.String=('零状态响应');
            app.UIAxes4.XLabel.String=('时间(t)') ;
            app.UIAxes4.YLabel.String=('幅值（f）');
            %app.UIAxes4.XAxis.Limits=[0,5];
            %app.UIAxes4.YAxis.Limits=[-0.2,0.2];
            
          
        end

        % Button down function: UIAxes5
        function UIAxes5ButtonDown(app, event)
            a=[1,3,1];
            b=[1,3];
            p=0.5;
            t=0:p:5;
            x=exp(-2*t);
            
            sys=tf(b,a);
            y=lsim(sys,x,t);
            plot(app.UIAxes5,t,y,'b');


            hold(app.UIAxes5,"on");
            p1=0.2;
            t1=0:p1:5;
            x1=exp(-2*t1);
            sys=tf(b,a);
            y1=lsim(sys,x1,t1);
            
            plot(app.UIAxes5,t1,y1,'g');
            hold(app.UIAxes5,"on");
            
            
            p2=0.01;
            t2=0:p2:5;
            x2=exp(-2*t2);
            y2=lsim(sys,x2,t2);
            
            plot(app.UIAxes5,t2,y2,'r');
            %title('实指数信号的零状态响应');
            hold(app.UIAxes5,"on");
            grid(app.UIAxes5,"on");
            
            plot(app.UIAxes5,t2,x2,'k');
            
            legend(app.UIAxes5,{'p=0.5','p=0.2','p=0.01','实指数信号'});
            
            app.UIAxes5.Title.String=('实指数信号的零状态响应');
            app.UIAxes5.XLabel.String=('时间(t)') ;
            app.UIAxes5.YLabel.String=('幅值（f）');
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            a=[1,5,6];
            b=[1];
            t=0:0.08:10;
            h=app.EditField.Value;
            x=[exp(h*(-t))];

            plot(app.UIAxes6,x,t,'b');
            hold(app.UIAxes6,"on");

            sys=tf(b,a);
            y=lsim(sys,x,t);
            plot(app.UIAxes6,t,y,'r');
          
            hold(app.UIAxes6,"off");
            legend(app.UIAxes6,{'输入信号','零状态响应'});


            app.UIAxes6.Title.String=('零状态响应');
            app.UIAxes6.XLabel.String=('时间(t)') ;
            app.UIAxes6.YLabel.String=('幅值（f）');
            app.UIAxes6.XAxis.Limits=[0,5];
            app.UIAxes6.YAxis.Limits=[0,1];
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            delete(app.UIF2);
            run app1.mlapp;
            return
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIF2 = uifigure('Visible', 'off');
            app.UIF2.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIF2);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '连续信号的卷积';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Tab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.Tab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.TextArea = uitextarea(app.Tab);
            app.TextArea.Value = {'数值计算法求f1(t)=u(t)-0.5u(t-2)与f2(t)=2e^(-3t)u(t)的卷积积分,其中u(t)代表阶跃信号'};
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始绘图';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '系统响应';
            app.UIAxes2 = uiaxes(app.Tab_2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Tab_2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.Tab_2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.yt4yt6ytftftLabel = uilabel(app.Tab_2);
            app.yt4yt6ytftftLabel.Text = '7y" (t)+4y''(t)+6y(t)=f''(t)+f(t)';
            app.Label = uilabel(app.Tab_2);
            app.Label.Text = '冲激响应';
            app.Label_2 = uilabel(app.Tab_2);
            app.Label_2.Text = '阶跃响应';
            app.Label_3 = uilabel(app.Tab_2);
            app.Label_3.Text = '零状态响应';
            app.Button_2 = uibutton(app.Tab_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '开始绘图';
            app.TextArea_3 = uitextarea(app.Tab_2);
            app.TextArea_3.Value = {'激励信号f=0.5+A*square(w*t,50)'; 't=0:0.01:10'};
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '实指数信号的零状态响应';
            app.UIAxes5 = uiaxes(app.Tab_3);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.ButtonDownFcn = createCallbackFcn(app, @UIAxes5ButtonDown, true);
            app.Label_4 = uilabel(app.Tab_3);
            app.Label_4.Text = '实指数信号的零状态响应';
            app.TextArea_2 = uitextarea(app.Tab_3);
            app.TextArea_2.Value = {'图中蓝线，绿线，红线，分别代表p=0.5,p=0.2,p=0.01。显而易见， 函数Isim( )对系统响应进行仿真的效果取决于向量t的时间间隔的密集程度，该图绘出了上述系统在不 同抽样时间间隔时函数lsim( )的仿真情况，由图可见，抽样时间间隔越小仿真效果越好。'};
            app.t0p5xexp2tLabel = uilabel(app.Tab_3);
            app.t0p5xexp2tLabel.Text = {' t=0:p:5;'; '    x=exp(-2*t);'};
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '可变数值零状态响应';
            app.UIAxes6 = uiaxes(app.Tab_4);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.EditField = uieditfield(app.Tab_4, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label_5 = uilabel(app.Tab_4);
            app.Label_5.Text = '';
            app.yt5yt6ytftLabel = uilabel(app.Tab_4);
            app.yt5yt6ytftLabel.Text = '1y" (t)+5y''(t)+6y(t)=f(t)';
            app.fteLabel = uilabel(app.Tab_4);
            app.fteLabel.Text = '输入信号为f(t)=e^(-';
            app.txLabel = uilabel(app.Tab_4);
            app.txLabel.Text = 't)x  的零状态响应';
            app.Button_3 = uibutton(app.Tab_4, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '返回主页';
            % Show the figure after all components are created
            app.UIF2.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Z2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIF2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIF2)
        end
    end
end