classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIF       matlab.ui.Figure
        Image     matlab.ui.control.Image
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_4  matlab.ui.control.Button
        Button_5  matlab.ui.control.Button
        ZButton   matlab.ui.control.Button
        Button_7  matlab.ui.control.Button
        Button_9  matlab.ui.control.Button
        TextArea  matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            run z1.mlapp;
            delete(app.UIF);
            return
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            run Z2.mlapp;
            delete(app.UIF);
            return
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            run z3.mlapp;
            delete(app.UIF);
            return
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            run z4.mlapp;
            delete(app.UIF);
            return
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            run z5.mlapp;
            delete(app.UIF);
            return
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            run z6.mlapp;
            delete(app.UIF);
            return
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            run z10.mlapp;
            delete(app.UIF);
            return
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            delete app.UIF;
            close all hidden;
            return
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIF = uifigure('Visible', 'off');
            app.UIF.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIF);
            app.TextArea.Value = {'基于MATLAB的信号与系统实验的仿真'};
            app.Button_9 = uibutton(app.UIF, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '退出';
            app.Button_7 = uibutton(app.UIF, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '连续信号的采样与恢复';
            app.ZButton = uibutton(app.UIF, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '离散时间系统的Z域分析';
            app.Button_5 = uibutton(app.UIF, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '离散时间信号与系统的时域分析';
            app.Button_4 = uibutton(app.UIF, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '连续时间系统的复频域分析';
            app.Button_3 = uibutton(app.UIF, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '连续时间系统的频域分析';
            app.Button_2 = uibutton(app.UIF, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '连续时间系统的时域分析';
            app.Button = uibutton(app.UIF, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '连续时间信号的分析';
            app.Image = uiimage(app.UIF);
            app.Image.ImageSource = 'Snip20220409_52.png';
            % Show the figure after all components are created
            app.UIF.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIF)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIF)
        end
    end
end