classdef try5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        UIAxes2   matlab.ui.control.UIAxes
        pic       matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %app.UIAxes.AutoResizeChildren = 'off';
            %ax1=app.UIAxes;
            %ax2=app.UIAxes;
            x=0:0.01:10 ;
            %设定x范围【0，10】，且步长为0.01
            y=sin(x);
            y1=cos(x);
            %ax1=subplot(2,1,1,'parent',app.UIAxes);
            %plot(ax1,x,y,'r');


            %ax2=subplot(2,1,2,'parent',app.UIAxes);
            %plot(ax2,x,y1,'b');

            
            app.UIAxes.Title.String=('函数图像显示器');
            app.UIAxes.XLabel.String=('时间(t)') ;
            app.UIAxes.YLabel.String=('幅值（f）');
            %ax.XAxis.Limits=[-1,10];
            %ax.YAxis.Limits=[-1.2,5];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %读取图像
            %绝对路径和相对路径之间的关系
            file="/Users/sunshi1/Downloads/akal.JPG";
            %在matlab中im开头的系统函数 几乎和图像处理有关 im=image
            im=imread(file);
            imshow(im,'Parent',app.pic);
            axis(app.pic,'off') %隐藏边框的函数
        end

        % Callback function
        function Button_3Pushed(app, event)
 app.Panel.AutoResizeChildren = 'off';
    ax1 = subplot(2,1,1,'Parent',app.Panel);
    x   = linspace(0,10);
    y1  = sin(x);
    plot(ax1,x,y1)
            
    ax2 = subplot(2,1,2,'Parent',app.Panel);
    y2  = sin(5*x);
    plot(ax2,x,y2)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.pic = uiaxes(app.UIFigure);
            title(app.pic, {'pic'; ''})
            xlabel(app.pic, ' ')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '绘图';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '显示图像';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = try5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end