classdef z1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIF1      matlab.ui.Figure
        TabGroup  matlab.ui.container.TabGroup
        Tab       matlab.ui.container.Tab
        Label_6   matlab.ui.control.Label
        Label_5   matlab.ui.control.Label
        Label_4   matlab.ui.control.Label
        Label_3   matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
        Label     matlab.ui.control.Label
        UIAxes6   matlab.ui.control.UIAxes
        UIAxes5   matlab.ui.control.UIAxes
        UIAxes4   matlab.ui.control.UIAxes
        UIAxes3   matlab.ui.control.UIAxes
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
        Tab2      matlab.ui.container.Tab
        TextArea  matlab.ui.control.TextArea
        Button    matlab.ui.control.Button
        Label_9   matlab.ui.control.Label
        Label_8   matlab.ui.control.Label
        Label_7   matlab.ui.control.Label
        UIAxes9   matlab.ui.control.UIAxes
        UIAxes8   matlab.ui.control.UIAxes
        UIAxes7   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            t= -10:1/500:10;
            x=sinc(t/pi);
            plot(app.UIAxes,t,x,"r");
            grid(app.UIAxes,"on");
            
            app.UIAxes.Title.String=('连续抽样信号');
            app.UIAxes.XLabel.String=('时间(t)') ;
            app.UIAxes.YLabel.String=('幅值（f）');
            app.UIAxes.XAxis.Limits=[-10,10];
            app.UIAxes.YAxis.Limits=[-1,1.2];
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            t=1:1:50;
            N=length(t);
            Y=rand(1,N);
            plot(app.UIAxes2,t,Y,'r');
            
            app.UIAxes2.Title.String=('随机信号');
            app.UIAxes2.XLabel.String=('时间(t)') ;
            app.UIAxes2.YLabel.String=('幅值（f）');
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            t0=0;t1=-1;t2=3;dt=0.001;
            t=t1:dt:t2;
            n=length(t);
            k1=floor((t0-t1)/dt);
            x=zeros(1,n);
            x(k1)=1/dt;
            stairs(app.UIAxes3,t,x,'r');
           
            
            app.UIAxes3.Title.String=('单位冲击信号');
            app.UIAxes3.XLabel.String=('时间(t)') ;
            app.UIAxes3.YLabel.String=('幅值（f）');
            app.UIAxes3.XAxis.Limits=[-1,3];
            app.UIAxes3.YAxis.Limits=[-1,22];
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
            t1=-1;t2=5;dt=0.01;
            t=t1:dt:t2;
            n=sign(t);
            plot(app.UIAxes4,t,n,'r');
            app.UIAxes4.Title.String=('符号信号');
            app.UIAxes4.XLabel.String=('时间(t)') ;
            app.UIAxes4.YLabel.String=('幅值（f）');
            app.UIAxes4.XAxis.Limits=[-1,5];
            app.UIAxes4.YAxis.Limits=[-1.5,1.5];
        end

        % Button down function: UIAxes5
        function UIAxes5ButtonDown(app, event)
            t=0:0.01:10;
            A=1;
            a=-0.4;
            xt=A*exp(a*t);
            plot(app.UIAxes5,t,xt,"r");
            app.UIAxes5.Title.String=('指数信号');
            app.UIAxes5.XLabel.String=('时间(t)') ;
            app.UIAxes5.YLabel.String=('幅值（f）');
        end

        % Button down function: UIAxes6
        function UIAxes6ButtonDown(app, event)
            t=-3:0.01:3;
            ft=tripuls(t,4,0.5);
            plot(app.UIAxes6,t,ft,'r');
            app.UIAxes6.Title.String=('三角信号');
            app.UIAxes6.XLabel.String=('时间(t)') ;
            app.UIAxes6.YLabel.String=('幅值（f）');
            app.UIAxes6.XAxis.Limits=[-3,3];
            app.UIAxes6.YAxis.Limits=[-0.5,1.5];
        end

        % Button down function: UIAxes7
        function UIAxes7ButtonDown(app, event)
            f0=2;
            t=0:0.001:2.5;
            w0=2*pi*f0;
            y=square(w0*t,50);       %占空比50
            plot(app.UIAxes7,t,y,'r');
            app.UIAxes7.Title.String=('周期方波信号');
            app.UIAxes7.XLabel.String=('时间(t)') ;
            app.UIAxes7.YLabel.String=('幅值（f）');
            app.UIAxes7.XAxis.Limits=[0,2.5];
            app.UIAxes7.YAxis.Limits=[-1.5,1.5];
        end

        % Button down function: UIAxes8
        function UIAxes8ButtonDown(app, event)
            t=-2: 0.02: 6;
            x=rectpuls(t-2,4);
            plot(app.UIAxes8,t,x,'r');
            app.UIAxes8.Title.String=('矩形脉冲信号');
            app.UIAxes8.XLabel.String=('时间(t)') ;
            app.UIAxes8.YLabel.String=('幅值（f）');
            app.UIAxes8.XAxis.Limits=[-2,6];
            app.UIAxes8.YAxis.Limits=[-0.5,1.5];
        end

        % Button down function: UIAxes9
        function UIAxes9ButtonDown(app, event)
            ax=app.UIAxes9;
            x=-1:0.01:10 ;
            %设定x范围【-1，10】，且步长为0.01
            y=sin(x);
            y1=cos(x);
            y2=tan(x);
            plot(ax,x,y,'r');
            hold(ax,"on");
            plot(ax,x,y1,'b');
            hold(ax,"on");
            plot(ax,x,y2,'g');
            legend(ax,{'正弦信号','余弦信号','正切信号'});
            app.UIAxes9.Title.String=('正/余弦、正切函数图像');
            app.UIAxes9.XLabel.String=('时间(t)') ;
            app.UIAxes9.YLabel.String=('幅值（f）');
            ax.XAxis.Limits=[-1,10];
            ax.YAxis.Limits=[-1.2,5];
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            run app1.mlapp;
            delete(app.UIF1);
            return
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIF1 = uifigure('Visible', 'off');
            app.UIF1.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIF1);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.Tab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.UIAxes4 = uiaxes(app.Tab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.UIAxes5 = uiaxes(app.Tab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.ButtonDownFcn = createCallbackFcn(app, @UIAxes5ButtonDown, true);
            app.UIAxes6 = uiaxes(app.Tab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.ButtonDownFcn = createCallbackFcn(app, @UIAxes6ButtonDown, true);
            app.Label = uilabel(app.Tab);
            app.Label.Text = '连续抽样信号';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '随机信号';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '单位冲击信号';
            app.Label_4 = uilabel(app.Tab);
            app.Label_4.Text = '符号信号';
            app.Label_5 = uilabel(app.Tab);
            app.Label_5.Text = '指数信号';
            app.Label_6 = uilabel(app.Tab);
            app.Label_6.Text = '三角信号';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes7 = uiaxes(app.Tab2);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes7.ButtonDownFcn = createCallbackFcn(app, @UIAxes7ButtonDown, true);
            app.UIAxes8 = uiaxes(app.Tab2);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8.ButtonDownFcn = createCallbackFcn(app, @UIAxes8ButtonDown, true);
            app.UIAxes9 = uiaxes(app.Tab2);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes9.ButtonDownFcn = createCallbackFcn(app, @UIAxes9ButtonDown, true);
            app.Label_7 = uilabel(app.Tab2);
            app.Label_7.Text = '周期方波信号';
            app.Label_8 = uilabel(app.Tab2);
            app.Label_8.Text = '矩形脉冲信号';
            app.Label_9 = uilabel(app.Tab2);
            app.Label_9.Text = '正/余弦、正切信号';
            app.Button = uibutton(app.Tab2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '返回主页';
            app.TextArea = uitextarea(app.Tab2);
            app.TextArea.Value = {'红色——正弦信号'; '蓝色——余弦信号'; '绿色——正切信号'};
            % Show the figure after all components are created
            app.UIF1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = z1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIF1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIF1)
        end
    end
end