classdef z10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIF10                matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        Tab                  matlab.ui.container.Tab
        EditField            matlab.ui.control.EditField
        EditFieldLabel       matlab.ui.control.Label
        HZEditField_2        matlab.ui.control.NumericEditField
        HZLabel_2            matlab.ui.control.Label
        HZEditField          matlab.ui.control.NumericEditField
        HZLabel              matlab.ui.control.Label
        HZKnob_2             matlab.ui.control.Knob
        HZKnob_2Label        matlab.ui.control.Label
        HZKnob               matlab.ui.control.Knob
        HZKnobLabel          matlab.ui.control.Label
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes_4             matlab.ui.control.UIAxes
        UIAxes_5             matlab.ui.control.UIAxes
        UIAxes_6             matlab.ui.control.UIAxes
        UIAxes_7             matlab.ui.control.UIAxes
        UIAxes_9             matlab.ui.control.UIAxes
        UIAxes_11            matlab.ui.control.UIAxes
        Tab2                 matlab.ui.container.Tab
        Button_2             matlab.ui.control.Button
        EditField_2          matlab.ui.control.NumericEditField
        Label                matlab.ui.control.Label
        stoptimeSlider       matlab.ui.control.Slider
        stoptimeSliderLabel  matlab.ui.control.Label
        Button               matlab.ui.control.Button
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: HZKnob
        function HZKnobValueChanging(app, event)
            changingValue = event.Value;     %随转盘的改变实时改变信号频率
            app.HZEditField.Value=changingValue;
            f=app.HZEditField.Value;   %信号频率为自定义Hz
            t=(0:0.0001:1);    %定义信号的时间范围
            x=cos(2*pi*f*t);     %生成信号

            %            plot(app.UIAxes,t,x,'r');
            %            app.UIAxes.YAxis.Limits=[-1,1];
            %             app.UIAxes.XLabel.String=('t/s') ;
            % %

            %plot(app.UIAxes_2,t,x,T,x1,'rp');
            %app.UIAxes_2.YAxis.Limits=[-1,1];
            %             app.UIAxes_2.XLabel.String=('t/s') ;
            %
            %             stem(app.UIAxes_3,T,x1,'r');
            %             app.UIAxes_3.YAxis.Limits=[-1,1];
            %             app.UIAxes_3.XLabel.String=('t/s') ;

            %w1=2*pi*10;             %显示[-20*pi 20*pi]范围内的频谱
            N=200;                 %计算出2*1000+1个频率点的值
            k=0:N;
            wk=k*100/N;
            F=x*exp(-j*t'*wk)*0.0001; %利用数值计算连续信号的傅里叶变换
            F=abs(F);    %计算频谱的幅度
            wk=[-fliplr(wk),wk(2:201)];
            F=[fliplr(F),F(2:201)];  %计算对应负频率的频谱


            plot(app.UIAxes_5,t,x,'r');
            app.UIAxes_5.XLabel.String=('t') ;
            app.UIAxes_5.YLabel.String=('f(t)') ;

            plot(app.UIAxes_4,wk,F,'r');
            app.UIAxes_4.XLabel.String=('w') ;
            app.UIAxes_4.YLabel.String=('F(jw)') ;

        end

        % Value changing function: HZKnob_2
        function HZKnob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.HZKnob_2.Value=changingValue ;
            app.HZEditField_2.Value=app.HZKnob_2.Value
            fs=app.HZKnob_2.Value;
            f=app.HZEditField.Value;
            t=0:0.0001:1;                %采样周期

            %w1=2*pi*10;             %显示[-20*pi 20*pi]范围内的频谱
            N=200;                 %计算出2*1000+1个频率点的值
            k=0:N;
            wk=k*100/N;
            dt=1/fs;
            T=0:dt:1; %采样间隔，采样间隔其实就可以理解为是采样信号的周期，周期=1/频率
            %T=(0:N-1)*dt;
            x=cos(2*pi*f*t);
            x1=cos(2*pi*f*T);
            plot(app.UIAxes_2,t,x,T,x1,'rp');
            app.UIAxes_2.YAxis.Limits=[-1,1];
            app.UIAxes_2.XLabel.String=('t/s') ;

            %             stem(app.UIAxes_3,T,x1,'r');
            %             app.UIAxes_3.YAxis.Limits=[-1,1];
            %             app.UIAxes_3.XLabel.String=('t/s') ;
            %
            t1=0:1/fs:1;

            F=x*exp(-j*t'*wk)*dt;    %利用数值计算采样信号的傅里叶变换
            F=abs(F);
            wk=[-fliplr(wk),wk(2:201)];  %将正频率扩展到对称的负频率
            F=[fliplr(F),F(2:201)];  %将正频率的频谱扩展到对称的负频率的频谱
            stem(app.UIAxes_7,T,x1,'r'); %采样信号的离散时间显示
            app.UIAxes_7.XLabel.String=('n') ;
            app.UIAxes_7.YLabel.String=('f(n)') ;
            %             F1=x*exp(-j*T'*wk);
            %             F1=abs(F1);

            plot(app.UIAxes_6,wk,F,'r');   %显示采样信号的连续的幅度谱
            app.UIAxes_6.XLabel.String=('w') ;
            app.UIAxes_6.YLabel.String=('F(jw)') ;
            %
            %     a1=app.HZKnob.Value;
            %             a2=app.HZKnob_2.Value;
            %             N = 1000;
            %             k = -N : N;
            %             W = k * 2000 / N;
            %             Nsampling = 1/a2; % ????
            %             t = 0: Nsampling : 1;
            %             f_80Hz = cos(2 * pi * a1 * t);
            %             F_80Hz = f_80Hz * exp(-1i * t' * W) * Nsampling; % ?????????
            %             F_80Hz = abs(F_80Hz);
            %
            % stem(app.UIAxes_7,t,f_80Hz)
            %             plot(app.UIAxes_6, W,F_80Hz)
            %
            n = -100 : 100;
            a3=f*2;
            dt= 1/fs;
            n_sam = n * dt;
            x = cos(2 * pi * f * n_sam) ;

            y = x * sinc((1/dt) * (ones(length(n_sam), 1) * t - n_sam' * ones(1, length(t))));
            plot(app.UIAxes_9, t,y);
            if(fs>=a3)
                app.EditField.Value=('符合奈奎斯特采样定理，信号可以复原');
            else
                app.EditField.Value=('不符合奈奎斯特采样定理，信号无法复原');
            end

            %              F=x*exp(-j*t'*wk)*dt;
            %              Tw=100/N;  %频率采样间隔
            %              w=-100:Tw:100;
            %              H=dt*rectpuls(w,1.*100/dt);  %理想低通滤波器频率特性
            %              Fa=F.*H;
            Dt=0.0001;
            t1=0:Dt:1;
            %              ws=2*pi/dt;
            %             wc=ws/2;
            %             % fa=Tw/(1*100)*(Fa*exp(j*wk'*t1));
            %             fb=dt*wc/pi*(x*sinc(wc/pi*(ones(length(t),1)*t1-t'*ones(1,length(t1)))));
            %              plot(app.UIAxes_9,t1,fb,'r');
            %             app.UIAxes_9.XLabel.String=('t') ;
            %             app.UIAxes_9.YLabel.String=('fa(t)') ;

            err=y-cos(2*pi*f*t1);
            plot(app.UIAxes_11,t1,err,'r');
            app.UIAxes_11.XLabel.String=('t') ;
            app.UIAxes_11.YLabel.String=('err(t)') ;
            sum(abs(err).^2)/length(err)


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            assignin("base",'period',app.EditField_2.Value);
            simout=sim('untitled.slx','stoptime',num2str(app.stoptimeSlider.Value));
            plot(app.UIAxes2,simout.maichongchouyangValue.Time,simout.maichongchouyangValue.Data);
            app.UIAxes2.YAxis.Limits=[-1,2];
            app.UIAxes2.XAxis.Limits=[0,2];
            legend(app.UIAxes2,'Pulse Generator');
            
            plot(app.UIAxes,simout.sineValue.Time,simout.sineValue.Data);
            app.UIAxes.XAxis.Limits=[0,2];
            legend(app.UIAxes,'Sine Wave');
            
            plot(app.UIAxes3,simout.chouyanghouValue.Time,simout.chouyanghouValue.Data);
            app.UIAxes3.XAxis.Limits=[0,2];
            legend(app.UIAxes3,'Product');
            
            plot(app.UIAxes4,simout.huifuValue.Time,simout.huifuValue.Data);
            app.UIAxes4.XAxis.Limits=[0,2];
            legend(app.UIAxes4,'Gain');
            return
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app.UIF10);
            run app1.mlapp;
            return
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIF10 = uifigure('Visible', 'off');
            app.UIF10.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIF10);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes_11 = uiaxes(app.Tab);
            title(app.UIAxes_11, '重构信号均方误差')
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_9 = uiaxes(app.Tab);
            title(app.UIAxes_9, '重构信号的时域图')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_7 = uiaxes(app.Tab);
            title(app.UIAxes_7, '采样信号的时域图')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_6 = uiaxes(app.Tab);
            title(app.UIAxes_6, '采样信号的频域图')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_5 = uiaxes(app.Tab);
            title(app.UIAxes_5, '原始信号的时域图')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_4 = uiaxes(app.Tab);
            title(app.UIAxes_4, '原始信号的频域图')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_2 = uiaxes(app.Tab);
            title(app.UIAxes_2, '采样过程')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.HZKnobLabel = uilabel(app.Tab);
            app.HZKnobLabel.Text = '信号频率(HZ)';
            app.HZKnob = uiknob(app.Tab, 'continuous');
            app.HZKnob.Limits = [0 20];
            app.HZKnob.ValueChangingFcn = createCallbackFcn(app, @HZKnobValueChanging, true);
            app.HZKnob_2Label = uilabel(app.Tab);
            app.HZKnob_2Label.Text = '采样频率(HZ)';
            app.HZKnob_2 = uiknob(app.Tab, 'continuous');
            app.HZKnob_2.Limits = [0 60];
            app.HZKnob_2.ValueChangingFcn = createCallbackFcn(app, @HZKnob_2ValueChanging, true);
            app.HZLabel = uilabel(app.Tab);
            app.HZLabel.Text = {'信号频率(HZ)'; ''};
            app.HZEditField = uieditfield(app.Tab, 'numeric');
            app.HZLabel_2 = uilabel(app.Tab);
            app.HZLabel_2.Text = {'采样频率(HZ)'; ''};
            app.HZEditField_2 = uieditfield(app.Tab, 'numeric');
            app.EditFieldLabel = uilabel(app.Tab);
            app.EditFieldLabel.Text = '结论';
            app.EditField = uieditfield(app.Tab, 'text');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.Tab2);
            title(app.UIAxes, '原始信号波形')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, '抽样信号脉冲')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Tab2);
            title(app.UIAxes3, '抽样后的信号波形')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.Tab2);
            title(app.UIAxes4, '恢复的信号波形')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Button = uibutton(app.Tab2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '运行';
            app.stoptimeSliderLabel = uilabel(app.Tab2);
            app.stoptimeSliderLabel.Text = 'stop time';
            app.stoptimeSlider = uislider(app.Tab2);
            app.stoptimeSlider.Limits = [0 20];
            app.Label = uilabel(app.Tab2);
            app.Label.Text = '抽样频率';
            app.EditField_2 = uieditfield(app.Tab2, 'numeric');
            app.Button_2 = uibutton(app.Tab2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '返回主页';
            % Show the figure after all components are created
            app.UIF10.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = z10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIF10)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIF10)
        end
    end
end