classdef z3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIF3                    matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        TextArea                matlab.ui.control.TextArea
        EditField_2             matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        EditField               matlab.ui.control.NumericEditField
        Label_3                 matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
        Tab_2                   matlab.ui.container.Tab
        TextArea_2              matlab.ui.control.TextArea
        yt4yt12ytft3tbodeLabel  matlab.ui.control.Label
        Button                  matlab.ui.control.Button
        UIAxes6                 matlab.ui.control.UIAxes
        UIAxes5                 matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        Tab_3                   matlab.ui.container.Tab
        Button_3                matlab.ui.control.Button
        yt3yt2ytftLabel         matlab.ui.control.Label
        Button_2                matlab.ui.control.Button
        UIAxes8                 matlab.ui.control.UIAxes
        UIAxes7                 matlab.ui.control.UIAxes
        UIAxes5_3               matlab.ui.control.UIAxes
        UIAxes5_2               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            t=0:0.01:2*pi;
            h=value;
            ax=app.UIAxes;
            y=h*sin(t);
            plot(app.UIAxes,t,y,'r'),hold(app.UIAxes,"on");

            y1=h*(sin(t)+sin(3*t)/3);
            plot(ax,t,y1,'b'),hold(app.UIAxes,"on");

            y2=h*(sin(t)+sin(3*t)/3+sin(5*t)/5);
            plot(ax,t,y2,'g'),hold(app.UIAxes,"on");

            y3=h*(sin(t)+sin(3*t)/3+sin(5*t)/5+sin(7*t)/7);
            plot(ax,t,y3,'k'),hold(app.UIAxes,"on");

            y4=h*(sin(t)+sin(3*t)/3+sin(5*t)/5+sin(7*t)/7+sin(9*t)/9);
            plot(ax,t,y4,"Color",[0.8500 0.3250 0.0980]),hold(app.UIAxes,"on");

            y5=h*(sin(t)+sin(3*t)/3+sin(5*t)/5+sin(7*t)/7+sin(9*t)/9+sin(11*t)/11);
            plot(ax,t,y5,'m');
            legend(ax,{'y','y1','y2','y3','y4','y5'});
            hold(ax,"off");


            grid(ax,"on");


            app.UIAxes.Title.String=('方波傅里叶分析');
            app.UIAxes.XLabel.String=('时间(t)') ;
            app.UIAxes.YLabel.String=('幅值（f）');
            return;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            a = app.EditField_2.Value;
            x=sin(a*2*pi*(1:8)/8)*5;
            dt=2*pi/8;
            w=linspace(0,2*pi,1000)/dt;
            x=x*exp(-1i*(1:length(x))'*w)*dt;
            plot(app.UIAxes2,w,abs(x),'r');
            
            app.UIAxes2.Title.String=('快速傅里叶变换');
            app.UIAxes2.XLabel.String=('时间(t)') ;
            app.UIAxes2.YLabel.String=('幅值（f）');
          
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            b=[1 3];
            a=[1 4 12];
            w=linspace(0,20,1000);
            h=freqs(b,a,w);
            mag = abs(h);
            phase = angle(h);
            phasedeg = phase*180/pi;
            semilogx(app.UIAxes3,w,20*log(mag),'b');
            grid(app.UIAxes3,"on");
            app.UIAxes3.Title.String=('Magenitude Response');
            app.UIAxes3.XLabel.String=('\omega(rad/s)') ;
            app.UIAxes3.YLabel.String=('幅频特性');

            semilogx(app.UIAxes4,w,phasedeg,'r');
            app.UIAxes4.Title.String=('phase Response');
            app.UIAxes4.XLabel.String=('\omega(rad/s)') ;
            app.UIAxes4.YLabel.String=('相频特性）');
            grid(app.UIAxes4,"on");
            d=impulse(b,a,w);
            plot(app.UIAxes5,w,d,'r');
            app.UIAxes5.Title.String=('Impulse Response');
            app.UIAxes5.XLabel.String=('t(s)') ;
            app.UIAxes5.YLabel.String=('h(t)');

            ps=roots(a);     %求极点
            zs=roots(b);       %求零点
            plot(app.UIAxes6,real(zs),imag(zs),'ro',real(ps),imag(ps),'bx','MarkerSize',12);
            app.UIAxes6.XAxis.Limits=[-4,0.5];
            app.UIAxes6.YAxis.Limits=[-3.5,3.5];
            app.UIAxes6.Title.String=('零极点分布');
            grid(app.UIAxes6,"on");
            grid(app.UIAxes6,"minor");


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            num=[ 0 1 0 ];
            den=[1 3 2 0 ];
            sys=tf(num,den);
            
            ps=roots(den);     %求极点
            zs=roots(num);       %求零点
            plot(app.UIAxes7,real(zs),imag(zs),'ro',real(ps),imag(ps),'bx','MarkerSize',12);
            app.UIAxes7.XAxis.Limits=[-2.5,0.5];
            app.UIAxes7.YAxis.Limits=[ -0.5,0.5 ];
            app.UIAxes7.Title.String=('零极点分布');
            grid(app.UIAxes7,"on");
            grid(app.UIAxes7,"minor");


            t=0:0.02:10;
            h=impulse(num,den,t);
            plot(app.UIAxes5_2,t,h,'r');
            app.UIAxes5_2.Title.String=('Impulse Response');
            app.UIAxes5_2.XLabel.String=('t(s)') ;
            app.UIAxes5_2.YLabel.String=('h(t)');

            [H,w]=freqs(num,den);
            semilogx(app.UIAxes5_3,w,20*log(abs(H)),'r');
            app.UIAxes5_3.Title.String=('Magenitude Response');
            app.UIAxes5_3.XLabel.String=('\omega(rad/s)') ;
            app.UIAxes5_3.YLabel.String=('|H(j\omega)|');
            grid(app.UIAxes5_3,"on");
            phase = angle(H);
            phasedeg= phase*180/pi;
            semilogx(app.UIAxes8,w,phasedeg,'r');
            app.UIAxes8.Title.String=('phase Response');
            app.UIAxes8.XLabel.String=('\omega(rad/s)') ;
            app.UIAxes8.YLabel.String=('相频特性）');
            grid(app.UIAxes8,"on");
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            delete(app.UIF3);
           
            run app1.mlapp;
            return
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIF3 = uifigure('Visible', 'off');
            app.UIF3.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIF3);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '傅里叶变换';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Label = uilabel(app.Tab);
            app.Label.Text = '方波傅里叶变换';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '快速傅里叶变换';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '输入方波的幅度';
            app.EditField = uieditfield(app.Tab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label_4 = uilabel(app.Tab);
            app.Label_4.Text = '输入幅度';
            app.EditField_2 = uieditfield(app.Tab, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.TextArea = uitextarea(app.Tab);
            app.TextArea.Value = {'y=h*sin(t)'; 'y1=h*(sin(t)+sin(3t)/3)'; 'y2=h*(sin(t)+sin(3t)/3+sin(5t)/5)'; 'y3=h*(sin(t)+sin(3t)/3+sin(5t)/5+sin(7t)/7)'; 'y4=h(sin(t)+sin(3t)/3+sin(5t)/5+sin(7t)/7+sin(9t)/9)'; 'y5=h(sin(t)+sin(3t)/3+sin(5t)/5+sin(7t)/7+sin(9t)/9+sin(11t)/11)'};
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '频域分析';
            app.UIAxes3 = uiaxes(app.Tab_2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.Tab_2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.Tab_2);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.Tab_2);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Button = uibutton(app.Tab_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始绘图';
            app.yt4yt12ytft3tbodeLabel = uilabel(app.Tab_2);
            app.yt4yt12ytft3tbodeLabel.Text = '1y" (t)+4y''(t)+12y(t)=f''(t)+3(t)  的频域分析，幅频、相频响应曲线，bode图';
            app.TextArea_2 = uitextarea(app.Tab_2);
            app.TextArea_2.Value = {'freqs函数、abs-幅度谱、angle-相位谱、取值范围0～20、抽取1000个频点'};
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '零极点分布';
            app.UIAxes5_2 = uiaxes(app.Tab_3);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.UIAxes5_3 = uiaxes(app.Tab_3);
            title(app.UIAxes5_3, 'Title')
            xlabel(app.UIAxes5_3, 'X')
            ylabel(app.UIAxes5_3, 'Y')
            zlabel(app.UIAxes5_3, 'Z')
            app.UIAxes7 = uiaxes(app.Tab_3);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.Tab_3);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.Button_2 = uibutton(app.Tab_3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '开始绘图';
            app.yt3yt2ytftLabel = uilabel(app.Tab_3);
            app.yt3yt2ytftLabel.Text = '1y" (t)+3y''(t)+2y(t)=f''(t)  的频域分析，幅频、相频响应曲线,零极点分布图';
            app.Button_3 = uibutton(app.Tab_3, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '返回主页';
            % Show the figure after all components are created
            app.UIF3.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = z3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIF3)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIF3)
        end
    end
end