classdef z4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIF4              matlab.ui.Figure
        Hsss32s22s1Label  matlab.ui.control.Label
        TextArea          matlab.ui.control.TextArea
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        UIAxes4           matlab.ui.control.UIAxes
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            num=[1 ];
            den=[1 2 2 1];
            sys=tf(num,den);
            ps=roots(den);     %求极点
            zs=roots(num);       %求零点
            plot(app.UIAxes,real(zs),imag(zs),'ro',real(ps),imag(ps),'bx','MarkerSize',12);
            grid(app.UIAxes,"on");
            grid(app.UIAxes,"minor");
            app.UIAxes.Title.String=('零极点分布');
            app.UIAxes.XAxis.Limits=[-1.2,0.2];
            app.UIAxes.YAxis.Limits=[-1,1];

            % bode(sys);
            %app.UIAxes3.XAxis.Limits=[-2,2];
            %app.UIAxes3.YAxis.Limits=[-2,2];
            %legeng(app.UIAxes,{'极点','零点'});

            t=0:0.01:20;
            h=impulse(num,den,t);
            plot(app.UIAxes3,t,h,'r');     %单位冲激响应

            app.UIAxes3.XLabel.String=('t(s)') ;
            app.UIAxes3.YLabel.String=('h(t)');
            app.UIAxes3.Title.String=('Impulse Response');
            %[H,w]=freqs(num,den);      %频率响应
            [mag,phase,w]=bode(num,den);

            semilogx(app.UIAxes2,w,20*log(mag(:)),'b');  %幅频响应
            app.UIAxes2.XLabel.String=('\omega(rad/s)') ;
            app.UIAxes2.YLabel.String=('|H(j\omega)|');
            app.UIAxes2.Title.String=('Magenitude Response');
            grid(app.UIAxes2,"on");
            %phase = angle(H);
            %phasedeg= phase*180/pi;
            semilogx(app.UIAxes4,w,phase(:),'r');  %相频响应
            app.UIAxes4.Title.String=('phase Response');
            app.UIAxes4.XLabel.String=('\omega(rad/s)') ;
            app.UIAxes4.YLabel.String=('相频特性）');
            grid(app.UIAxes4,"on");
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app.UIF4);
            
            run app1.mlapp;
            return
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIF4 = uifigure('Visible', 'off');
            app.UIF4.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIF4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIF4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIF4);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIF4);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Button = uibutton(app.UIF4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始绘图';
            app.Button_2 = uibutton(app.UIF4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '返回主页';
            app.TextArea = uitextarea(app.UIF4);
            app.TextArea.Value = {'三个极点全在s左平面内，故该系统是稳定系统'};
            app.Hsss32s22s1Label = uilabel(app.UIF4);
            app.Hsss32s22s1Label.Text = 'H(s)=s/(s^3+2s^2+2s+1)  单位冲激响应、频率响应、极点分布';
            % Show the figure after all components are created
            app.UIF4.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = z4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIF4)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIF4)
        end
    end
end