classdef z6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIF6      matlab.ui.Figure
        TextArea  matlab.ui.control.TextArea
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        UIAxes4   matlab.ui.control.UIAxes
        UIAxes3   matlab.ui.control.UIAxes
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            num=[0 1 2 1];
            den=[1 -0.5 -0.005 0.3];
            
            z = roots(num);
            p = roots(den);
            plot(app.UIAxes,real(z),imag(z),'ro');
            hold(app.UIAxes,"on");
            plot(app.UIAxes,real(p),imag(p),'bx');
            legend(app.UIAxes,{'零点','极点'});
             
            grid(app.UIAxes,"minor");
            
            k=0:40;
            h=impz(num,den,k);
            stem(app.UIAxes3,k,h,'r');
            app.UIAxes3.XLabel.String=('k') ;
            app.UIAxes3.YLabel.String=('h[k]');
            [mag,phase,w]=bode(num,den);
            bode(num,den);
            %[H,w]=freqz(num,den);
            semilogx(app.UIAxes2,w,20*log(mag(:)),'r');
            app.UIAxes2.XLabel.String=('ang.freq.\Omega(rad/s)') ;
            app.UIAxes2.YLabel.String=('|H(e^j^\Omega)|');
            grid(app.UIAxes2,"on");
            semilogx(app.UIAxes4,w,phase(:),'r');
            app.UIAxes4.XLabel.String=('ang.freq.\Omega(rad/s)') ;
            app.UIAxes4.YLabel.String=('Angle');
            grid(app.UIAxes4,"on");


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app.UIF6);
            run app1.mlapp;
            
            return
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIF6 = uifigure('Visible', 'off');
            app.UIF6.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIF6);
            title(app.UIAxes, '零极点分布')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIF6);
            title(app.UIAxes2, 'Magenitude Response')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIF6);
            title(app.UIAxes3, 'Impulse Response')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIF6);
            title(app.UIAxes4, 'phase Response')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Button = uibutton(app.UIF6, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始绘图';
            app.Button_2 = uibutton(app.UIF6, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '返回主页';
            app.TextArea = uitextarea(app.UIF6);
            app.TextArea.Value = {'H(z)=(z^2+2z+1)/(z^3-0.5z^2-0.005z+0.3'};
            % Show the figure after all components are created
            app.UIF6.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = z6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIF6)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIF6)
        end
    end
end